classdef mousetimer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EntryNoLabel                    matlab.ui.control.Label
        EntryNoPlaceholderLabel         matlab.ui.control.Label
        RobotNamePlaceholderLabel       matlab.ui.control.Label
        CompetitorNamePlaceholderLabel  matlab.ui.control.Label
        RunTimePlaceholderLabel         matlab.ui.control.Label
        TryCountLabel                   matlab.ui.control.Label
        TryCountPlaceholderLabel        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TimeRemainingPlaceholderLabel   matlab.ui.control.Label
        TimeRemainingLabel              matlab.ui.control.Label
        RankPlaceholderLabel            matlab.ui.control.Label
        BestTimePlaceholderLabel        matlab.ui.control.Label
        RankLabel                       matlab.ui.control.Label
        BestTimeLabel                   matlab.ui.control.Label
        RankingPlaceholderTable         matlab.ui.control.Table
        TryScorePlaceholderTable        matlab.ui.control.Table
        StartButton                     matlab.ui.control.Button
        GoalButton                      matlab.ui.control.Button
        TouchButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        BeginButton                     matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        timerHandle = []; % Handle of the timer for periodic update
        
        tryStartTime = 0; % Timestamp when started
        tryStopTime = 0; % Timestamp when stopped
        entryStartTime = 0;
        entryPauseOffsetDuration = 0;
        
        startFlag = false;
        goalFlag = false;
        retireFlag = false;
        beginFlag = false;
        pauseFlag = false;
        
        tryCount = 0;
        maxTryCount = 5;
        maxEntryDuration = 5*60;
        
        entryNum = 0;
        robotName = 'だんごろマウスNull';
        competitorName = 'dango_bot';
        affiliationName = '東京工業大学ロボット技術研究会Cheese';
        
        beginSoundPlayer = audioplayer([0], 44100);
        startSoundPlayer = audioplayer([0], 44100);
        goalSoundPlayer = audioplayer([0], 44100);
        retireSoundPlayer = audioplayer([0], 44100);
        timeSoundPlayer = audioplayer([0], 44100);
        
        %serialオブジェクト変数(startupFcnで初期化)
        esp32_serial = [];           
    end
        methods (Access = private)
            
            %周期的に実行される処理(時刻表示更新・シリアルデータ取得)
            function periodicUpdate(app)
                
                %beginとかボタンとかの処理チェックが入る前にシリアル読み込みした方がよいのか？
                [detection_time,sensor_id,success_flag] = serialRead(app,'@',5,2);
                %success_flag = 0;
                %センサデータ取得時
                if success_flag
                    %start/goalボタンと同じ処理
                    changeStateBySensor(app,sensor_id,detection_time);
                end
                
                
                % Update the time remaining
                if app.beginFlag
                    remainingTimeStr = formatTime(app, seconds(app.maxEntryDuration - seconds(datetime('now') - app.entryStartTime)));
                    remainingTimeStr(end-3:end)=[];
                    app.TimeRemainingPlaceholderLabel.Text = remainingTimeStr;
                end
                
                % Update run time
                if app.retireFlag
                    app.RunTimePlaceholderLabel.Text = 'R';
                    app.RunTimePlaceholderLabel.FontColor = 'r';
                elseif app.startFlag
                    if app.goalFlag
                        tryBestTime = app.tryStopTime;
                        app.RunTimePlaceholderLabel.FontColor = 'g';
                    else
                        tryBestTime = datetime('now');
                        app.RunTimePlaceholderLabel.FontColor = 'w';
                    end
                    runtimeStr = formatTime(app, tryBestTime - app.tryStartTime);
                    app.RunTimePlaceholderLabel.Text = runtimeStr;
                else
                    app.RunTimePlaceholderLabel.Text = formatTime(app, seconds(0));
                    app.RunTimePlaceholderLabel.FontColor = 'w';
                end


            end
            
            function commitRecord(app, recordTime)
                % Update record table
                tableData = app.TryScorePlaceholderTable.Data;
                if app.retireFlag
                    tableData = [tableData; {num2str(app.tryCount), 'R'}];
                else
                    tableData = [tableData; {num2str(app.tryCount), formatTime(app, recordTime)}];
                end
                app.TryScorePlaceholderTable.Data = tableData;
                
                % Update ranking table
                if ~app.retireFlag
                    tableData = app.RankingPlaceholderTable.Data(:,2:end);
                    if numel(tableData) > 0 && ismember(app.robotName, tableData(:,2))
                        [~, currentRobotIdx] = ismember(app.robotName, tableData(:,2));
                        tableData(currentRobotIdx,:) = [];
                    end
                    tableData = [tableData; {formatTime(app, recordTime), app.robotName}];
                    tableData = sortrows(tableData);
                    numberCells = cellfun(@num2str, num2cell((1:size(tableData,1))'), 'UniformOutput', false);
                    app.RankingPlaceholderTable.Data = [numberCells, tableData];
                    
                    % Update best record
                    [~, currentRobotIdx] = ismember(app.robotName, tableData(:,2));
                    app.RankPlaceholderLabel.Text = num2str(currentRobotIdx);
                    app.BestTimePlaceholderLabel.Text = tableData(currentRobotIdx,1);
                end
            end
            
            %秒数 => 時刻表記
            function str = formatTime(app, time)
                totalSecs = seconds(time);
                mins = floor(totalSecs / 60);
                secs = floor(totalSecs - mins * 60);
                subsecs = totalSecs - mins * 60 - secs;
                str = sprintf('%02d:%02d.%03d', mins, secs, floor(subsecs*1000));
            end
            
            %ランキング記録・走行記録　保存
            function saveData(app)
                rankingData = app.RankingPlaceholderTable.Data;
                individualData = app.TryScorePlaceholderTable.Data;
                
                save('ranking.mat', 'rankingData');
                save(['robot' num2str(app.entryNum) '.mat'], 'individualData');
                
                uialert(app.UIFigure, 'Saved', 'Saved', 'Icon', 'success');
            end
            
            %serial readまでまとめた関数
            function [time,sensor_id,success_flag] = serialRead(app,mark,time_length,id_length)
                
                strings = [];
                count = [];
                msg = [];
                %1行読み込み.1行データが無かったら10msでtimeout
                if ~isempty(app.esp32_serial)
                    [strings,count,msg] = fgetl(app.esp32_serial);
                end
                
                %読み取り失敗なら無文字列返す準備
                time = "";
                sensor_id = "";
                success_flag = isempty(msg);
                
                %読み取り成功
                if success_flag
                    mark_index = strfind(strings,mark);
                    
                    %markの直後にデータが存在
                    if (mark_index + time_length + id_length + 1) <= length(strings)
                        time = datetime('now');%str(mark_index + 1 : mark_index + time_length);
                        sensor_id = strings( mark_index + time_length + 2 : mark_index + time_length + id_length + 1);
                    end
                end
            end


            %反応したセンサに応じて処理実行
            function changeStateBySensor(app,sensor_id,detection_time)
                if sensor_id == "01"
                    % 01 : startの意
                    %startボタンが押されたときと同じ動作をするべき
                    startButtonPushedProcess(app,detection_time);
                    
                elseif sensor_id  == "02"
                    %02 : goalの意
                    %goalボタンが押されたときと同じ動作をするべき
                    goalButtonPusedProcess(app,detection_time);
                end
                
            end
            
            %goalボタンが押された・startのセンサが反応した時にする処理(押された・反応した時刻を引数に)
            %コード量が増えると見づらくなるので共通化してしまったが，無理くりtimeを引数にとってやるのは何かどうなんだろう？誰かに聞きたい．
            function goalButtonPusedProcess(app,now_time)
                if ~app.beginFlag || ~app.startFlag || app.goalFlag
                    return
                end
                
                app.tryStopTime = now_time;
                app.goalFlag = true;
                app.retireFlag = false;
                app.pauseFlag = false;
                commitRecord(app, app.tryStopTime-app.tryStartTime);
                play(app.goalSoundPlayer);
            end
            
            %startボタンが押された・startのセンサが反応した時にする処理(押された・反応した時刻を引数に)
            function startButtonPushedProcess(app,now_time)
                if ~app.beginFlag || app.maxTryCount == app.tryCount
                    return
                end
                if app.startFlag
                    % The robot came back to the start, prepare for the autostart
                    app.startFlag = false;
                    return
                end
                
                app.tryStartTime = now_time;
                app.startFlag = true;
                app.goalFlag = false;
                app.retireFlag = false;
                app.pauseFlag = false;
                app.tryCount = app.tryCount + 1;
                app.TryCountPlaceholderLabel.Text = num2str(app.tryCount);
                play(app.startSoundPlayer);
            end
            
        end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, entryNum, robotName, competitorName, affiliationName)
                %nargin = input argument number
                if nargin > 1
                    app.entryNum = entryNum;
                end
                if nargin > 2
                    app.robotName = robotName;
                end
                if nargin > 3
                    app.competitorName = competitorName;
                end
                if nargin > 4
                    app.affiliationName = affiliationName;
                end
                
                app.RobotNamePlaceholderLabel.Text = app.robotName;
                app.EntryNoPlaceholderLabel.Text = sprintf('%02d', app.entryNum);
                app.CompetitorNamePlaceholderLabel.Text = [app.competitorName ' (' app.affiliationName ')'];
                
                if exist('assets/begin.wav', 'file')
                    [y, Fs] = audioread('assets/begin.wav');
                    app.beginSoundPlayer = audioplayer(y, Fs);
                end
                if exist('assets/start.wav', 'file')
                    [y, Fs] = audioread('assets/start.wav');
                    app.startSoundPlayer = audioplayer(y, Fs);
                end
                if exist('assets/goal.wav', 'file')
                    [y, Fs] = audioread('assets/goal.wav');
                    app.goalSoundPlayer = audioplayer(y, Fs);
                end
                if exist('assets/retire.wav', 'file')
                    [y, Fs] = audioread('assets/retire.wav');
                    app.retireSoundPlayer = audioplayer(y, Fs);
                end
                if exist('assets/time.wav', 'file')
                    [y, Fs] = audioread('assets/time.wav');
                    app.timeSoundPlayer = audioplayer(y, Fs);
                end
                
                if exist('ranking.mat', 'file')
                    data = load('ranking.mat');
                    app.RankingPlaceholderTable.Data = data.rankingData;
                end
                if exist(['robot' num2str(app.entryNum) '.mat'], 'file')
                    msg = sprintf('Record file for No.%02d is found. Do you want to load it?', app.entryNum);
                    title = 'Load Record';
                    selection = uiconfirm(app.UIFigure,msg,title,...
                        'Options',{'Yes','No'},...
                        'DefaultOption',1,'CancelOption',2);
                    if strcmp(selection, 'Yes')
                        data = load(['robot' num2str(app.entryNum) '.mat']);
                        app.TryScorePlaceholderTable.Data = data.individualData;
                        app.tryCount = str2double(data.individualData(end,1));
                        app.TryCountPlaceholderLabel.Text = num2str(app.tryCount);
                    end
                end
                
                %timerがモジュール．timerHandleは自作変数
                app.timerHandle = timer;
                app.timerHandle.Period = 0.031;
                app.timerHandle.TasksToExecute = inf;
                app.timerHandle.ExecutionMode = 'fixedRate';
                app.timerHandle.TimerFcn = @(src, event) periodicUpdate(app);
                start(app.timerHandle);
                
                %serialObject初期化
                %COMは環境に合わせて変える必要ありそう
                serial_list = seriallist;
                if ~isempty( find(serial_list == "COM4",1) )
                    app.esp32_serial = serial('COM4','BaudRate',115200);
                    app.esp32_serial.Timeout = 0.01;   %10m秒でタイムアウト
                    fopen(app.esp32_serial);
                else
                    %serial_result = "serial failed"
                    %close;
                end
                
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                startButtonPushedProcess(app, datetime('now'));
        end

        % Button pushed function: GoalButton
        function GoalButtonPushed(app, event)
                goalButtonPusedProcess(app, datetime('now'));
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
                app.entryStartTime = datetime('now');
                app.entryPauseOffsetDuration = 0;
                app.startFlag = false;
                app.goalFlag = false;
                app.retireFlag = false;
                app.beginFlag = true;
                app.pauseFlag = false;
                play(app.beginSoundPlayer);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
                if ~app.beginFlag
                    return
                end
                
                app.pauseFlag = ~app.pauseFlag;
        end

        % Button pushed function: TouchButton
        function TouchButtonPushed(app, event)
                if ~app.startFlag
                    return
                end
                
                if app.goalFlag
                    app.retireFlag = false;
                else
                    app.retireFlag = true;
                    commitRecord(app, Inf);
                    play(app.retireSoundPlayer);
                end
                app.startFlag = false;
                app.goalFlag = false;
                app.pauseFlag = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                msg = 'Do you wish to save before closing?';
                title = 'Save before Closing?';
                selection = uiconfirm(app.UIFigure,msg,title,...
                    'Options',{'Save and Close','Close without Saving','Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch selection
                    case 'Cancel'
                        return
                    case 'Save and Close'
                        saveData(app);
                end
                
                stop(app.timerHandle);
                delete(app.timerHandle);
                
                %serial終了処理追加
                if ~isempty(app.esp32_serial)
                    fclose(app.esp32_serial);
                    delete(app.esp32_serial);
                end        
                delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                app.startFlag = false;
                app.goalFlag = false;
                app.retireFlag = false;
                app.pauseFlag = false;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                saveData(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EntryNoLabel = uilabel(app.UIFigure);
            app.EntryNoLabel.Text = 'Entry No.';
            app.EntryNoPlaceholderLabel = uilabel(app.UIFigure);
            app.EntryNoPlaceholderLabel.Text = '00';
            app.RobotNamePlaceholderLabel = uilabel(app.UIFigure);
            app.RobotNamePlaceholderLabel.Text = 'だんごろマウスNull';
            app.CompetitorNamePlaceholderLabel = uilabel(app.UIFigure);
            app.CompetitorNamePlaceholderLabel.Text = 'dango_bot (東京工業大学ロボット技術研究会Cheese)';
            app.RunTimePlaceholderLabel = uilabel(app.UIFigure);
            app.RunTimePlaceholderLabel.Text = '00:00.000';
            app.TryCountLabel = uilabel(app.UIFigure);
            app.TryCountLabel.Text = 'Try Count';
            app.TryCountPlaceholderLabel = uilabel(app.UIFigure);
            app.TryCountPlaceholderLabel.Text = '1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '/5';
            app.TimeRemainingPlaceholderLabel = uilabel(app.UIFigure);
            app.TimeRemainingPlaceholderLabel.Text = '05:00';
            app.TimeRemainingLabel = uilabel(app.UIFigure);
            app.TimeRemainingLabel.Text = 'Time Remaining';
            app.RankPlaceholderLabel = uilabel(app.UIFigure);
            app.RankPlaceholderLabel.Text = '1';
            app.BestTimePlaceholderLabel = uilabel(app.UIFigure);
            app.BestTimePlaceholderLabel.Text = '00:00.000';
            app.RankLabel = uilabel(app.UIFigure);
            app.RankLabel.Text = 'Rank';
            app.BestTimeLabel = uilabel(app.UIFigure);
            app.BestTimeLabel.Text = 'Best Time';
            app.RankingPlaceholderTable = uitable(app.UIFigure);
            app.RankingPlaceholderTable.ColumnName = {'Rank'; 'Run Time'; 'Robot Name'};
            app.RankingPlaceholderTable.RowName = {};
            app.TryScorePlaceholderTable = uitable(app.UIFigure);
            app.TryScorePlaceholderTable.ColumnName = {'Try'; 'Run Time'};
            app.TryScorePlaceholderTable.RowName = {};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GoalButton = uibutton(app.UIFigure, 'push');
            app.GoalButton.ButtonPushedFcn = createCallbackFcn(app, @GoalButtonPushed, true);
            app.GoalButton.Text = 'Goal';
            app.TouchButton = uibutton(app.UIFigure, 'push');
            app.TouchButton.ButtonPushedFcn = createCallbackFcn(app, @TouchButtonPushed, true);
            app.TouchButton.Text = 'Touch';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mousetimer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end